classdef MajorGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        SeasonLabel                    matlab.ui.control.Label
        SeasonEditField                matlab.ui.control.NumericEditField
        InitiateProgramButton          matlab.ui.control.Button
        DaytimeReadingTextAreaLabel    matlab.ui.control.Label
        DaytimeReadingTextArea         matlab.ui.control.TextArea
        SuggestionsLabel               matlab.ui.control.Label
        SuggestionsTextArea            matlab.ui.control.TextArea
        TemperatureReadingsCLabel      matlab.ui.control.Label
        Enteranumberfrom1to4forLabel   matlab.ui.control.Label
        theseasonswintertoautumnLabel  matlab.ui.control.Label
        IndoorTempInatorLabel          matlab.ui.control.Label
        Gauge                          matlab.ui.control.Gauge
        TextArea                       matlab.ui.control.TextArea
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: SeasonEditField
        function SeasonEditFieldValueChanged(app, event)
            global seasons_feedback
            seasons_feedback = app.SeasonEditField.Value;
            %Inspiried from MATLAB Tutorial
            
        end

        % Callback function: DaytimeReadingTextArea, 
        % InitiateProgramButton
        function ButtonPushed(app, event)
          global seasons_feedback
            grove = arduino('/dev/cu.usbserial-0001','Nano3');
            sample_light = 25;
            light_values = [];
            sample_temp = 100;
            temp_array = [];
            
            %Inspiried from lab H
             %Take light readings
    for j = 1:sample_light
    light_read = readVoltage(grove, 'A6');
    light_values = [light_values, light_read];
    end 
    
    light = mean(light_values);
    
    %Inspiried from Lab H
    %Take Temp Values
    for i = 1:sample_temp
  volt = readVoltage(grove, 'A0');
temp = (105.4713*volt) - 0.06196; 
temp_array = [temp_array, temp];
    end
    
% All app.x.Value functions were inspiried by MATLAB Tutorials    
average_temperature = mean(temp_array);
app.Gauge.Value = average_temperature
app.TextArea.Value = num2str(average_temperature)

%Create Conditional Statement From User Input
if seasons_feedback == 1
   
%Daytime Suggestions
    if light > 0.6
        % Read temperature, convert to celsuis
        app.DaytimeReadingTextArea.Value = 'Day'
        
        %Suggestions
        if average_temperature > 18 & average_temperature < 23
            Suggestion = 'Your current temperature is sustainable and keeping you comfy!' 
            app.SuggestionsTextArea.Value = Suggestion   
        elseif average_temperature > 23 
            app.SuggestionsTextArea.Value = 'Lowering heater temperature can save you money and keep you comfy!' 
        else 
            app.SuggestionsTextArea.Value ='It seems cold, feel free to up the thermostat' 
        end
        %Nighttime Suggestions
    elseif light < 0.6
         app.DaytimeReadingTextArea.Value =  'Night'
        if average_temperature < 16
            app.SuggestionsTextArea.Value = 'It seems cold, ideal sleeping temperature is between 16 and 19 degrees celsius'
        elseif average_temperature > 16 & average_temperature < 21 
            app.SuggestionsTextArea.Value = 'You are currently in the right sleep temperature'
        else 
            app.SuggestionsTextArea.Value = 'Temperature is above sleep temp. Open a window to cool the room down'
        end 
    end 
    %Dance of Sugar Plum Faries - Nutcracker
    playTone(grove, 'D5', 1568, .5)
    pause(0.5)
    playTone(grove, 'D5', 1318, .5)
    pause(0.5)
    playTone(grove, 'D5', 1568, .5)
    pause(0.65)
    playTone(grove, 'D5', 1480, .65)
    pause(0.65)
    playTone(grove, 'D5', 1244, .65)
    pause(0.65)
    playTone(grove, 'D5', 1318, .75)
    pause(2)
    
    % Spring
elseif seasons_feedback == 2
    
%Daytime Suggestions
    if light > 0.6
        % More Display Suggestions
         app.DaytimeReadingTextArea.Value = 'Day'
        if average_temperature > 18 & average_temperature < 24
    app.SuggestionsTextArea.Value = "Looks like you're staying comfy and being sustainable. Good job!" 
        elseif average_temperature > 24 
    app.SuggestionsTextArea.Value = 'Lowering heater temperature can save you money and keep you comfy!' 
        else 
   app.SuggestionsTextArea.Value = "It seems cold, feel free to up the thermostat" 
        end
        %Nighttime Suggestions
    elseif light < 0.6
         app.DaytimeReadingTextArea.Value= 'Night'
        if average_temperature < 16
            app.SuggestionsTextArea.Value = 'It seems cold, ideal sleeping temperature is between 16 and 19 degrees celsius'
        elseif average_temperature > 16 & average_temperature < 21 
            disp('You are currently in the right sleep temperature') 
        else 
            app.SuggestionsTextArea.Value = 'Temperature is above sleep temp. Open a window to cool the room down'
        end 
    end
   % Four Seasons: Spring - Vivaldi
playTone(grove, 'D5',658 , .3)
pause(0.31)
playTone(grove, 'D5', 784, .3)
pause(0.31)
playTone(grove, 'D5', 784, .3)
pause(0.31)
playTone(grove, 'D5', 784, .4)
pause(0.41)
playTone(grove, 'D5', 740, .2)
pause(0.15)
playTone(grove, 'D5', 658, .2)
pause(0.15)
playTone(grove, 'D5', 988, .65) 
pause(0.66)
%Possible song extention, I thought it didn't flow 
%playTone(grove, 'D5', 988, 0.3) 
%pause(0.31) 
%playTone(grove, 'D5', 932, 0.35)
%pause(0.36) 
%playTone(grove, 'D5', 880, 0.4)
%pause(0.41) 
%playTone(grove, 'D5', 932, 0.35)
%pause(0.35) 
%playTone(grove, 'D5', 988, 0.3) 
%pause(0.3) 
%playTone(grove, 'D5', 932, 0.4)
%pause(0.41) 
%playTone(grove, 'D5', 880, 0.4)
%pause(0.41) 
%playTone(grove, 'D5', 740, .65)
pause(2)

%Summer
elseif seasons_feedback == 3 
   
%Daytime Suggestions
    if light > 0.6
        % Read temperature, convert to celsuis
         app.DaytimeReadingTextArea.Value = 'Day'
        if average_temperature > 23 & average_temperature < 26
   app.SuggestionsTextArea.Value = "It feels comfy and sustainable!"
        elseif average_temperature > 26 
    app.SuggestionsTextArea.Value = 'It seems hot in here, turn on the AC'
        elseif average_temperature < 23 
     app.SuggestionsTextArea.Value = 'It seems cold. Turning off the AC can save money and keep you comfy!'
        end
        %Nighttime Suggestions
    elseif light < 0.6
         app.DaytimeReadingTextArea.Value = 'Night'
        if average_temperature < 16
            app.SuggestionsTextArea.Value =  "It seems cold, ideal sleeping temperature is between 16 and 19 degrees celsius. This might be higher if it's hot"
        elseif average_temperature > 16 & average_temperature < 22 
            app.SuggestionsTextArea.Value = 'You are currently in the right sleep temperature' 
        elseif average_temperature > 22 
            app.SuggestionsTextArea.Value = 'Temperature is above sleep temp. Open a window or turn on the AC to cool the room down'
        end 
    end 
%Bella Ciao - Traditional   
playTone(grove, 'D5', 165, .45)
pause(0.46) 
playTone(grove, 'D5', 220, .45)
pause(0.46) 
playTone(grove, 'D5', 247, .25)
pause(0.26) 
playTone(grove, 'D5', 262, .65)
pause(0.66) 
playTone(grove, 'D5', 247, .45)
pause(0.46) 
playTone(grove, 'D5', 220, .25)
pause(0.26) 
playTone(grove, 'D5', 262, .65)
pause(0.66) 
playTone(grove, 'D5', 247, .45)
pause(0.46) 
playTone(grove, 'D5', 220, .25)
pause(0.26) 
playTone(grove, 'D5', 330, .65)
pause(0.66)
playTone(grove, 'D5', 330, .65)
pause(0.66)
playTone(grove, 'D5', 330, .65)
pause(2)

%Autumn 
elseif seasons_feedback == 4 

%Daytime Suggestions
    if light > 0.6
         app.DaytimeReadingTextArea.Value = 'Day'
        if average_temperature > 18 & average_temperature < 22
    app.SuggestionsTextArea.Value = 'No suggestions thus far' 
        elseif average_temperature > 22 
    app.SuggestionsTextArea.Value = 'Lowering heater temperature can save you money and keep you comfy!'
        else 
    app.SuggestionsTextArea.Value =  "It seems cold, feel free to up the thermostat" 
        end
        %Nighttime Suggestions
    elseif light < 0.6
        app.DaytimeReadingTextArea.Value = 'Night'
        if average_temperature < 16
            app.SuggestionsTextArea.Value = 'It seems cold, ideal sleeping temperature is between 16 and 19 degrees celsius'
        elseif average_temperature > 16 & average_temperature < 21 
            app.SuggestionsTextArea.Value = 'You are currently in the right sleep temperature' 
        else 
            app.SuggestionsTextArea.Value = 'Temperature is above sleep temp. Open a window to cool the room down'
        end
    end 
    % Hall of the Mountain King - Grieg
    playTone(grove, 'D5', 110, .2)
    pause(0.21) 
    playTone(grove, 'D5', 124, .2)
    pause(0.21) 
    playTone(grove, 'D5', 131, .2)
    pause(0.21) 
    playTone(grove, 'D5', 147, .2)
    pause(0.21)
    playTone(grove, 'D5', 165, .2)
    pause(0.21)
    playTone(grove, 'D5', 131, .2)
    pause(0.21)
    playTone(grove, 'D5', 165, .2)
    pause(0.5)
    playTone(grove, 'D5', 155, .2)
    pause(0.21)
    playTone(grove, 'D5', 124, .2)
    pause(0.21)
    playTone(grove, 'D5', 155, .2)
    pause(0.5)
    playTone(grove, 'D5', 147, .2)
    pause(0.21)
    playTone(grove, 'D5', 155, .2)
    pause(0.21)
    playTone(grove, 'D5', 147, .2)
    pause(0.21)
else
     app.DaytimeReadingTextArea.Value = 'ERROR'
      app.SuggestionsTextArea.Value = 'ERROR: Enter valid number'
% The Night King - Ramin Djawadi
  playTone(grove, 'D5', 262, .2)
  pause(0.4)
  playTone(grove, 'D5', 262, 1)
  pause(1.01)
  playTone(grove, 'D5', 262, .2)
  pause(0.21) 
  playTone(grove, 'D5', 247, .4)
  pause(1)
  playTone(grove, 'D5', 175, .2)
  pause(0.21)
  playTone(grove, 'D5', 175, 1)
  pause(1.01) 
  playTone(grove, 'D5', 165, .5)
  pause(2)
end 


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SeasonLabel = uilabel(app.UIFigure);
            app.SeasonLabel.Text = 'Season:';
            app.SeasonEditField = uieditfield(app.UIFigure, 'numeric');
            app.SeasonEditField.ValueChangedFcn = createCallbackFcn(app, @SeasonEditFieldValueChanged, true);
            app.InitiateProgramButton = uibutton(app.UIFigure, 'push');
            app.InitiateProgramButton.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.InitiateProgramButton.Text = 'Initiate Program';
            app.DaytimeReadingTextAreaLabel = uilabel(app.UIFigure);
            app.DaytimeReadingTextAreaLabel.Text = 'Daytime Reading: ';
            app.DaytimeReadingTextArea = uitextarea(app.UIFigure);
            app.DaytimeReadingTextArea.ValueChangedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.DaytimeReadingTextArea.Value = {'Make sure to place grove board in natural light for accurate reading.'};
            app.SuggestionsLabel = uilabel(app.UIFigure);
            app.SuggestionsLabel.Text = 'Suggestions:';
            app.SuggestionsTextArea = uitextarea(app.UIFigure);
            app.SuggestionsTextArea.Value = {'Program will provide temperature related suggestions. These are based off indoor temperature and current lighting conditions.'};
            app.TemperatureReadingsCLabel = uilabel(app.UIFigure);
            app.TemperatureReadingsCLabel.Text = 'Temperature Readings [*C]';
            app.Enteranumberfrom1to4forLabel = uilabel(app.UIFigure);
            app.Enteranumberfrom1to4forLabel.Text = 'Enter a number from 1 to 4 for';
            app.theseasonswintertoautumnLabel = uilabel(app.UIFigure);
            app.theseasonswintertoautumnLabel.Text = ' the seasons winter to autumn';
            app.IndoorTempInatorLabel = uilabel(app.UIFigure);
            app.IndoorTempInatorLabel.Text = 'Indoor-Temp-Inator';
            app.Gauge = uigauge(app.UIFigure, 'circular');
            app.Gauge.Limits = [10 30];
            app.Gauge.MajorTicks = [10 11 12 13 14 15 16 17 18 19 20 21 22 23 24 25 26 27 28 29 30 31];
            app.Gauge.MajorTickLabels = {'10', '11', '12', '13', '14', '15', '16', '17', '18', '19', '20', '21', '22', '23', '24', '25', '26', '27', '28', '29', '30'};
            app.TextArea = uitextarea(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MajorGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end